classdef LogLogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ExportButton         matlab.ui.control.Button
        InvertABSSwitch      matlab.ui.control.ToggleSwitch
        InvertABSLabel       matlab.ui.control.Label
        FitEqnTextArea       matlab.ui.control.TextArea
        FitEqnTextAreaLabel  matlab.ui.control.Label
        WavelengthText       matlab.ui.control.Label
        xLabel               matlab.ui.control.Label
        MaxX                 matlab.ui.control.NumericEditField
        xLabel_2             matlab.ui.control.Label
        MinX                 matlab.ui.control.NumericEditField
        PrevButton           matlab.ui.control.Button
        NextButton           matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Store main app inside object CallingApp
        Index % Store Index from SpectraBuilderApp
        LinData % Store the LinData table passed to this app
        LogData % Store the LogData table passed to this app
        Time % Store the orginal X data
        LogTimeArray % Store the Log Array of the orginal X data
        InititalIndex % Specifiy the index of when the app started to return to
        plot_wavelengths % Store the names of the headers
        IndexSize % Store the size of KinInd
        LogLogABS % Store the uniform log Ydata
        LogLogTime % Store the uniform log Xdata
        xPoints % Store the x-data points of ROI object
        X1 % Store the 1st ROI line
        X2 % Store the 2nd ROI line
        Shade % Store shade array
        ShadeArea % Store shade area struct
        FitResult % Store the fit struct
        xData % Store prepared Xdata
        yData % Store prepared Ydata
        yFitData % Store fitted Ydata
        XPointsROI % Store ROI points in cell array
        XMin % Store minimum of uniform Xdata
        XMax % Store maximum of uniform Xdata
        Curve % Store fit plot
        Data % Store scatter plot
        LogPlot % Store plot variable only when log data is loaded in
        tempY % Temp Y variable for invert
        tempX % Temp x variable for invert
    end

    methods (Access = private)
        function [fitresult, gof] = createFit(app)
            %% Fit: 'Log-Log Graph' w/ check for inverse fit data
            [app.xData, app.yData] = prepareCurveData(app.LogLogTime, app.LogLogABS);
            
            % Set up fittype and options.
            ft = fittype( 'poly1' );
            excludedPoints = (app.xData < app.xPoints(1,1)) | (app.xData > app.xPoints(1,2));
            opts = fitoptions( 'Method', 'LinearLeastSquares' );
            opts.Robust = 'Bisquare';
            opts.Exclude = excludedPoints;

            % Fit model to data.
            [fitresult, gof] = fit( app.xData, app.yData, ft, opts );
            app.yFitData = feval(fitresult, app.xData);
            fchar = evalc('fitresult');
            fstring = convertCharsToStrings(fchar);
            app.FitEqnTextArea.Value = fstring;
            app.FitResult = fitresult;
            
            % To retrieve proper wavelengths/positions of observed data
            if app.CallingApp.TamOn == 0
                app.WavelengthText.Text = "Displaying @" + app.plot_wavelengths(app.Index-1) + "nm";
            else
                app.WavelengthText.Text = "Displaying " + app.CallingApp.positions(app.Index);
            end
        end

        function shader(app, ~, ~)
            % If called without callback execute default
            if nargin == 1
                app.Shade = [];
                app.xPoints = [];
                
                %% First time setup of plotting interactible area
                if isempty(app.UIAxes) == 0
                    
                    % Clear any existing plots
                    cla(app.UIAxes, 'reset');

                    % Default shaded area drawn/data & model
                    default(app);
                    createFit(app);
                    plotModel(app);
                    plotData(app);

                end

                %% Cell array to keep track of every ROI point position
                app.XPointsROI = [];

                %% Implement drawpoint for either side of the area
                if ~isnan(app.xPoints(1,1)) && ~isnan(app.xPoints(1,2))
                    app.X1 = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,1) -11; app.xPoints(1,1) 1], "Deletable",false,"InteractionsAllowed","translate");
                    app.X2 = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,2) -11; app.xPoints(1,2) 1], "Deletable",false,"InteractionsAllowed","translate");

                    %% Add callbacks for when point moves
                    addlistener(app.X1, "MovingROI", @(src,event)shader(app,src,event));
                    addlistener(app.X2, "MovingROI", @(src,event)shader(app,src,event));

                    %% Store points in vector
                    app.XPointsROI{1,1} = app.X1;
                    app.XPointsROI{1,2} = app.X2;
                end

                createFit(app);
            % If ROI area is changed through manual mouse input
            else

                app.ShadeArea{1,1} = [ ];
                app.ShadeArea{1,1}=[app.XPointsROI{1,1}.Position(1),app.XPointsROI{1,2}.Position(1)];


                % Normal default(app) with Shade deletions in the middle
                hold(app.UIAxes, 'on');
                RGarea = app.ShadeArea{1,1};
                AreaLeft = [app.XMin RGarea(1)];
                AreaRight = [RGarea(2) app.XMax];

                delete(app.Shade{1});
                delete(app.Shade{2});

                app.Shade{1} = area(app.UIAxes, AreaLeft, [-10 -10]);
                app.Shade{2} = area(app.UIAxes, AreaRight, [-10 -10]);
                set(app.Shade{1},'FaceColor',[0.5 0.5 0.5])
                set(app.Shade{2},'FaceColor',[0.5 0.5 0.5])
                hold(app.UIAxes, 'off');

                app.xPoints(1,1) = app.Shade{1}.XData(2);
                app.xPoints(1,2) = app.Shade{2}.XData(1);
                app.Shade{1}.BaseValue = app.UIAxes.YLim(2);


                % We just need shade on top/no need to redraw scatter data
                app.UIAxes.Children = flip(app.UIAxes.Children);
                
                % Continue
                createFit(app);
                plotModel(app);
            end
        end

        function default(app)
            app.XMin = min(app.LogLogTime);
            app.XMax = max(app.LogLogTime);
            
            %% Draw both shaded areas up until XLims
            hold(app.UIAxes, 'on');
            RGarea = app.ShadeArea{1,1};
            AreaLeft = [app.XMin RGarea(1)];
            AreaRight = [RGarea(2) app.XMax];

            app.Shade{1} = area(app.UIAxes, AreaLeft, [-10 -10]);
            app.Shade{2} = area(app.UIAxes, AreaRight, [-10 -10]);
            set(app.Shade{1},'FaceColor',[0.5 0.5 0.5])
            set(app.Shade{2},'FaceColor',[0.5 0.5 0.5])
            axis(app.UIAxes, [app.XMin app.XMax app.UIAxes.YLim(1) app.UIAxes.YLim(2)]);
            hold(app.UIAxes, 'off');

            %% Store Lower and Upper X coordinates
            app.xPoints(1,1) = app.Shade{1}.XData(2);
            app.xPoints(1,2) = app.Shade{2}.XData(1);

            % Set basevalue to look nice
            app.Shade{1,1}.BaseValue = app.UIAxes.YLim(2);
        end


        function plotModel(app)
            % Plot the model and its limits
            delete(app.Curve);

            var = app.UIAxes.YLim;
            hold(app.UIAxes, 'on' );
            app.Curve = plot(app.UIAxes, app.xData, app.yFitData, 'color', [0.9290 0.6940 0.1250]);
            hold (app.UIAxes, 'off');
            app.UIAxes.YLim = var;

            app.MinX.Value = app.xPoints(1,1);
            app.MaxX.Value = app.xPoints(1,2);
        end

        function plotData(app)
            % Plot the data as scatter
            delete(app.Data);
            hold(app.UIAxes, 'on' );
            app.Data = scatter(app.UIAxes, app.LogLogTime, app.LogLogABS, 10, [0 0.4470 0.7410], 'filled');
            hold (app.UIAxes, 'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SolarSpecGUI)
            % Store main app object and other input arguments
            app.CallingApp = SolarSpecGUI;
            app.LinData = app.CallingApp.LinearData;
            app.LogData = app.CallingApp.LogarithmData;
            app.Time = app.CallingApp.time;
            app.LogTimeArray = app.CallingApp.LogTimeArr;
            app.InititalIndex = app.CallingApp.KinInd;
            app.Index = app.CallingApp.KinInd;
            app.plot_wavelengths = app.CallingApp.Plot_Wavelengths;
            app.ShadeArea = [];
            
            %Proper startup to keep app looking clean at first
            app.InvertABSSwitch.Value = 'Off';
            
            %Re-apply the app components/labels
            app.UIAxes.Visible = 1;
            app.FitEqnTextArea.Visible = 1;
            app.WavelengthText.Visible = 1;
            app.MinX.Visible = 1;
            app.MaxX.Visible = 1;
            app.FitEqnTextAreaLabel.Visible = 1;
            app.xLabel.Visible = 1;
            app.xLabel_2.Visible = 1;
            app.InvertABSSwitch.Visible = 1;
            app.InvertABSLabel.Visible = 1;
            
            %Index variables for which files the GUI is viewing
            if ~app.CallingApp.IsLogDataOnly
                app.IndexSize = width(app.LogData);
            else
                app.IndexSize = width(app.CallingApp.Table);
            end
            
            app.Index = app.Index + 1;
            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear loaded Data
                app.LogLogABS = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));

                % Log of the Lin-Spaced Linear Data. No longer used
                %LogLinABS = real(log10(app.LinData{:,KinInd}(app.Time>0)));
                %LogLinTime = real(log10(app.LinData{:,1}(app.Time>0)));
            else
                % Log of the Log-Spaced loaded Data
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.LogLogABS = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end
            
            % Record a starting range for shaded area on startup
            if ~app.CallingApp.IsLogDataOnly
                default_shade = real(log10(app.LogData{:,2}(app.LogTimeArray>0)));
            else
                timeRG = table2array(app.CallingApp.Table(:,1));
                default_shade = real(log10(app.CallingApp.Table{:,2}(timeRG>0)));
            end
            
            % Use the starting range to update ShadedArea
            CenterTime = mean(default_shade);
                 
            if CenterTime < 0
                app.ShadeArea{1,1} = [CenterTime-1 CenterTime+1];
            else 
                app.ShadeArea{1,1} = [CenterTime+1 CenterTime-1];
            end
            
            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'Log-Log TAS Data Analysis');
            ylabel(app.UIAxes,'LogABS');
            xlabel(app.UIAxes,'LogTime');
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Reset inverse switch and go to prev data index
            app.InvertABSSwitch.Value = 'Off';
            app.Index = app.Index - 1;
            if app.Index < 2
                app.Index = 2;
            end

            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear Data
                app.LogLogABS = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));
            else
                % Loaded LogData
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.LogLogABS = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));  
            end

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'Log-Log TAS Data Analysis');
            ylabel(app.UIAxes,'LogABS');
            xlabel(app.UIAxes,'LogTime');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Reset inverse switch and go to next data index
            app.InvertABSSwitch.Value = 'Off';
            app.Index = app.Index + 1;
            if app.Index > app.IndexSize
                app.Index = app.IndexSize;
            end

            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear Data
                app.LogLogABS = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));
            else
                % Loaded LogData
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.LogLogABS = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'Log-Log TAS Data Analysis');
            ylabel(app.UIAxes,'LogABS');
            xlabel(app.UIAxes,'LogTime');
        end

        % Value changed function: MinX
        function MinXValueChanged(app, event)
            latestvalue = event.Value;
            
            % Keep the changed input within the boundary conditons
            if (latestvalue < app.XMin)
                latestvalue = app.XMin; %#ok<NASGU> 
            elseif (latestvalue > app.X2.Position(1))
                latestvalue = app.X2.Position(1) + 1; %#ok<NASGU> 
            else
                % Code to snap to nearest data point
                app.xPoints(1,1) = latestvalue;

                [~, line_index] = min(abs(app.LogLogTime - latestvalue));
                closest = app.LogLogTime(line_index);

                app.X1.Position = [closest -11; closest 1];

                delete(app.Shade{1});
                delete(app.Shade{2});

                app.ShadeArea{1,1} = [closest app.xPoints(1,2)];

                default(app);

                % We just need shaded area on top/no need to redraw scatter
                app.UIAxes.Children = flip(app.UIAxes.Children);
                createFit(app);
                plotModel(app);
            end
        end

        % Value changed function: MaxX
        function MaxXValueChanged(app, event)
            latestvalue = event.Value;
            
            % Keep the changed input within the boundary conditons
            if (latestvalue > app.XMax)
                latestvalue = app.XMax; %#ok<NASGU> 
            elseif (latestvalue < app.X1.Position(1))
                latestvalue = app.X1.Position(1) - 1; %#ok<NASGU> 
            else
                % Code to snap to nearest data point
                app.xPoints(1,2) = latestvalue;

                [~, line_index] = min(abs(app.LogLogTime - latestvalue));
                closest = app.LogLogTime(line_index);

                app.X2.Position = [closest -11; closest 1];

                delete(app.Shade{1});
                delete(app.Shade{2});

                app.ShadeArea{1,1} = [app.xPoints(1,1) closest];

                default(app);

                % We just need shaded area on top/no need to redraw scatter
                app.UIAxes.Children = flip(app.UIAxes.Children);
                createFit(app);
                plotModel(app);
            end
        end

        % Value changed function: InvertABSSwitch
        function InvertABSSwitchValueChanged(app, event)
            % Check the event value and proceed accordingly if inverted
            value = convertCharsToStrings(event.Value);
            
            if ~app.CallingApp.IsLogDataOnly
                if (value == 'On') %#ok<BDSCA> 
                    % Copy temp variables for when you switch back
                    app.tempY = app.LogLogABS;
                    app.tempX = app.LogLogTime;
                    
                    % Turn on and change data to inverted
                    inverseLog = (app.LogData{:,app.Index}).*(-1);
                    app.LogLogABS = real(log10(inverseLog(app.LogTimeArray<0)));
                    app.LogLogTime = real(log10(app.LogData{:,1}(app.LogTimeArray<0)));
    
                    createFit(app);
                    plotModel(app);
                    plotData(app);
                else
                    % Turn back off and return to normal data
                    app.LogLogABS = app.tempY;
                    app.LogLogTime = app.tempX;
    
                    createFit(app);
                    plotModel(app);
                    plotData(app);
                end
            else
                timeRG = table2array(app.CallingApp.Table(:,1));
                if (value == 'On') %#ok<BDSCA> 
                    % Copy temp variables for when you switch back
                    app.tempY = app.LogLogABS;
                    app.tempX = app.LogLogTime;
                    
                    % Turn on and change loaded log data to inverted
                    inverseLog = (app.CallingApp.Table{:,app.Index}).*(-1);
                    app.LogLogABS = real(log10(inverseLog(timeRG<0)));
                    app.LogLogTime = real(log10(app.CallingApp.Table{:,1}(timeRG<0)));
    
                    createFit(app);
                    plotModel(app);
                    plotData(app);
                else
                    % Turn back off and return to normal loaded log data
                    app.LogLogABS = app.tempY;
                    app.LogLogTime = app.tempX;
    
                    createFit(app);
                    plotModel(app);
                    plotData(app);
                end
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Store necessary variables for export
            time = app.LogLogTime;
            abs = app.LogLogABS;
            yfit = app.yFitData;
            fitwindow = [app.xPoints(1,1); app.xPoints(1,2)];
            fiteqn = [app.FitResult.p1; app.FitResult.p2];

            % Create blank array with titles
            p = {'LogTime', 'LogABS', 'yfitData', 'fitWindow', 'fitEquation'};
            A = nan(size(time,1),5);
            A(1:length(time),1) = time;
            A(1:length(abs),2) = abs;
            A(1:length(yfit),3) = yfit;
            A(1:length(fitwindow),4) = fitwindow;
            A(1:length(fiteqn),5) = fiteqn;

            % Convert 5 column vectors into table with appropriate names
            T = array2table(A);
            T.Properties.VariableNames(1:5) = p;

            % Add wavelength into title of filename
            f = sprintf('TASLogData%s.csv',app.WavelengthText.Text);
            place = fullfile(app.CallingApp.Path,f);
            writetable(T,place);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Reset index to same value as when you opened from SpecBuilder
            app.CallingApp.KinInd = app.InititalIndex;
            
            % Re-enable the figure button and then delete the app
            app.CallingApp.LogLogFigureButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Log-Log TAS Data Analysis')
            xlabel(app.UIAxes, 'LogTime')
            ylabel(app.UIAxes, 'LogABS')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.MinX = uieditfield(app.UIFigure, 'numeric');
            app.MinX.ValueChangedFcn = createCallbackFcn(app, @MinXValueChanged, true);
            app.xLabel_2 = uilabel(app.UIFigure);
            app.xLabel_2.Text = 'x <';
            app.MaxX = uieditfield(app.UIFigure, 'numeric');
            app.MaxX.ValueChangedFcn = createCallbackFcn(app, @MaxXValueChanged, true);
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x >';
            app.WavelengthText = uilabel(app.UIFigure);
            app.WavelengthText.Text = '';
            app.FitEqnTextAreaLabel = uilabel(app.UIFigure);
            app.FitEqnTextAreaLabel.Text = 'Fit Eq''n:';
            app.FitEqnTextArea = uitextarea(app.UIFigure);
            app.InvertABSLabel = uilabel(app.UIFigure);
            app.InvertABSLabel.Text = {'Invert '; 'ABS'};
            app.InvertABSSwitch = uiswitch(app.UIFigure, 'toggle');
            app.InvertABSSwitch.ValueChangedFcn = createCallbackFcn(app, @InvertABSSwitchValueChanged, true);
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogLogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end