classdef SolarSpecGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrabuilderUIFigure          matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        AddButton                       matlab.ui.control.Button
        CentertimesforspectraalongwiththeirwidthsLabel  matlab.ui.control.Label
        ExportLogDataCSVButton          matlab.ui.control.Button
        KineticLevelDisplay             matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        BackgroundLvlDisplay            matlab.ui.control.Label
        ExportSpectraDataButton         matlab.ui.control.Button
        PlotDetailsLabel                matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        KeepKinPlotaxisfixedCheckBox    matlab.ui.control.CheckBox
        CenterButton                    matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        LinearYscaleButton              matlab.ui.control.RadioButton
        LogYScaleButton                 matlab.ui.control.RadioButton
        GridLayout6                     matlab.ui.container.GridLayout
        NextButton                      matlab.ui.control.Button
        PrevButton                      matlab.ui.control.Button
        KeepSpecPlotYaxisfixedCheckBox  matlab.ui.control.CheckBox
        KinPlot                         matlab.ui.control.UIAxes
        SpecPlot                        matlab.ui.control.UIAxes
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        LogarithmicdatawillbeexportedtoCSVLabel  matlab.ui.control.Label
        LogLogFigureButton              matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ExportFiguresButton             matlab.ui.control.Button
        SaveVarstoWorkspaceButton       matlab.ui.control.Button
        LoadLogData                     matlab.ui.control.Button
        LoadCreateError                 matlab.ui.control.Label
        GridLayout9                     matlab.ui.container.GridLayout
        LinearDataButton                matlab.ui.control.Button
        LogDataButton                   matlab.ui.control.Button
        LoadTDMSdataButton              matlab.ui.control.Button
    end


    properties (Access = private)
        LogApp % Store the second application in this object
        waves % Store list of wavelengths from header names
        BackgroundLevel % Store BackgroundLvl table
        LinDataSort % Store the sorted LinData table
        LogDataSort % Store the sorted LogData table
        dataRG % Store the DataRG table
        timeRG % Store the TimeRG table
        KinData % Store the loaded log data for only once
        changeAxes % Store boolean changeAxes if the axes has to change
        FixedYRange % Store the range of Y to keep axes fixed
        SpecYRange % Store the range of Y of the spectra axis
        SpecDataPlot % Store the spec plot data of the specified index
        KinDataPlot % Store the kin plot data of the specified index
        DataPlot % Hold the kin plot object
        ShadeArea % Array to store area limits
        Traces % Store the number of traces
        XPoints % Array to store x location of ROI points
        XPointsROI % Store points within one function among multiple if else
        KinShade % Store shade among one functinon
        rst % To determine whether or not to reload data folder
        iter % Iterator for load button use
        TimeWidths % Store time width table
        TimeFields % Store time centres in table rather than UIObject
    end

    properties (Access = public)
        Path % Store the path of the data folder
        TamOn % Store whether TAM data is being used or not
        positions % Store list of positions from header names

        % These properties are essentially the input arguments for LogLog app
        LinearData % Store the LinData table
        LogarithmData % Store the LogData table
        time % Store the original X data
        LogTimeArr % Store the Log of the X data in an array
        KinInd % Store the index of which data file we are looking at
        Plot_Wavelengths % Sorted string2double wavelengths nums
        Table % Store the temporary LogTable T to use in functions
        IsLogDataOnly % Store if LogData is loaded and not calculated
    end

    methods (Access = private)

        function RGPlot(app)
            % Sort the wavelgenths headers
            app.Plot_Wavelengths = sort(str2double(app.waves));

            % Clear plot to ensure no overlap
            cla(app.SpecPlot, 'reset');

            % Set number of colors for lines
            RGcolor = hsv(8);
            RGcolor(:,3) = 0.5;
            RGcolor(4, 3) = 1;

            % Keep X scale Logarithmic
            app.KinPlot.XScale = 'log';

            % Plot reference Y = 0 line if Y scale is linear.
            if strcmp(app.KinPlot.YScale,'linear') == 1
                KinRef = refline(app.KinPlot, 0, 0);
                KinRef.Color = [0 0 0];
            end

            % Plot Spectral data and check if there is more than one
            % tdms file opened. Change plot accordingly
            [m,~] = size(app.SpecDataPlot);
            if m > 1
                for i = 1:1:size(app.SpecDataPlot,2)
                    plot(app.SpecPlot, app.Plot_Wavelengths, app.SpecDataPlot(:,i), 'Color', RGcolor(i,:));
                    hold(app.SpecPlot, 'on');
                end
                SpecRef = refline(app.SpecPlot,0,0);
                SpecRef.Color = [0 0 0];
                hold(app.SpecPlot, 'off');

                if app.changeAxes
                    if app.TamOn == 0
                        axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    else
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    end
                else
                    if app.TamOn == 0
                        axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) app.SpecYRange]);
                    end
                end
            else
                for i = 1:1:size(app.SpecDataPlot,2)
                    plot(app.SpecPlot, app.Plot_Wavelengths, app.SpecDataPlot(:,i), 'Color', RGcolor(i,:));
                    hold(app.SpecPlot, 'on');
                end
                SpecRef = refline(app.SpecPlot,0,0);
                SpecRef.Color = [0 0 0];
                hold(app.SpecPlot, 'off');

                if app.changeAxes
                    if app.TamOn == 0
                        if app.Plot_Wavelengths(1) == max(app.Plot_Wavelengths)
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1 min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                            app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                        else
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))]);
                            app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                        end
                    else
                        app.SpecYRange = [min(min(app.SpecDataPlot)) max(max(app.SpecDataPlot))];
                    end
                else
                    if app.TamOn == 0
                        if app.Plot_Wavelengths(1) == max(app.Plot_Wavelengths)
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) max(app.Plot_Wavelengths) app.SpecYRange]);
                        else
                            axis(app.SpecPlot, [app.Plot_Wavelengths(1) app.Plot_Wavelengths(1)+1 app.SpecYRange]);
                        end
                    end
                end
            end

            %% Display the proper wavelengths/position values depending
            % on TAS of TAM data
            if app.TamOn == 0
                app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
                app.BackgroundLvlDisplay.Text = "Background level was " + table2array(app.BackgroundLevel(1,app.KinInd)) + " V"; %RG
            else
                app.KineticLevelDisplay.Text = "Disp: " + app.positions(app.KinInd);
                app.BackgroundLvlDisplay.Text = "Background level was " + table2array(app.BackgroundLevel(1,app.KinInd)) + " V";
            end
            
            %% Display new UITable values
            data(:,1) = arrayfun(@(x) sprintf('%.2e\n',x),app.TimeFields,'un',0);
            data(:,2) = num2cell(round((app.TimeWidths./app.TimeFields).*100));
            
            app.UITable.Data = data;
            
            app.KinPlot.Title.String = "Kinetics";
            app.KinPlot.XLabel.String = "Time (s)";
            app.KinPlot.YLabel.String = "ΔOD or %Abs";
            app.SpecPlot.Title.String = "Spectra";
            app.SpecPlot.XLabel.String = "Wavelength (nm)";
            app.SpecPlot.YLabel.String = "ΔOD or %Abs";
        end

        function RGBuildSpecData(app, ~, event)

            %% If called without callback execute default
            if nargin == 1

                % Populate correct properties for spec data plot
                app.timeRG = app.dataRG(:,1);
                TimeRGSpec = app.LinDataSort(:,1);
                app.Traces = size(app.LinDataSort, 2) - 1;

                % Create vars to use for proper use of slider object/input
                SpecCenterTimes = app.TimeFields;
                SpecWidth = 0.10;

                app.SpecDataPlot = [];

                if isempty(get(app.KinPlot, 'Children')) == 1

                    %% On first draw, set Graph
                    app.XPoints = [];
                    app.ShadeArea = [];

                    for Spec = 1:1:size(SpecCenterTimes)
                        CenterTime = SpecCenterTimes(Spec);

                        StartTime = CenterTime *(1 - SpecWidth/2);
                        EndTime = CenterTime * (1 + SpecWidth/2);

                        StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                        EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                        app.SpecDataPlot(:,Spec) = mean(table2array(app.LinDataSort(StartInd:EndInd,2:end)),1);
                        app.ShadeArea(Spec,1:2) = [StartTime EndTime];
                        app.TimeWidths(Spec,1) = EndTime - StartTime;
                    end

                    % Cell array to keep track of shade area
                    app.KinShade = [];

                    %% Start of Plotting interactible area
                    grid(app.KinPlot, 'off')

                    updateData(app);
                    YCheck(app);
                    drawAreas(app);
                    plotKinData(app);
%                     app.SpectrabuilderUIFigure.Children = flip(app.SpectrabuilderUIFigure.Children);

                else
                    Tester(app);
                    updateData(app);
                    plotKinData(app);
                    YCheck(app);
                end
                RGPlot(app);
            else   
                %% Deal with callback; handle mouse input
                % Variables carrying all the new data
                eventSource = get(event.Source);
                ind = str2double(eventSource.Tag);
                
                % Conditional for different listeners (reshape v translate)
                newwidth = eventSource.Position(3);
                oldwidth = app.TimeWidths(ind);
                currentCenter = app.TimeFields(ind);
                
                %reshape
                if ~(oldwidth == newwidth)
                    
                    if ~(eventSource.Position(1) == app.ShadeArea(ind,1))
                        %then manually reshaping left side so do the right
                        app.TimeWidths(ind) = 2*(currentCenter - eventSource.Position(1));
                        app.XPointsROI{ind}.Position(3) = app.TimeWidths(ind);
                    else
                        %then manually reshaping right side so do the left
                        right = eventSource.Position(1) + eventSource.Position(3);
%                         delta = right - app.ShadeArea(ind,2);
                        
                        widl = 2*(right - currentCenter)/currentCenter;
                        
                        StartTime = currentCenter *(1 - widl/2);
                        EndTime = currentCenter * (1 + widl/2);
                        
                        tst1 = app.XPointsROI{ind}.Position(1);
                        tst2 = app.TimeWidths(ind);
                        tst3 = app.XPointsROI{ind}.Position(3);
                        
                        app.XPointsROI{ind}.Position(1) = StartTime;
                        app.TimeWidths(ind) = EndTime - StartTime;
                        app.XPointsROI{ind}.Position(3) = app.TimeWidths(ind);
                        
                        tst4 = app.XPointsROI{ind}.Position(1);
                        tst5 = app.TimeWidths(ind);
                        tst6 = app.XPointsROI{ind}.Position(3);
                        
                    end
                    
                %translate
                else
                    original = app.ShadeArea(ind,1);
                    new = eventSource.Position(1);
                    
                    % translate to the right
                    if (new - original) > 0
                    
                    delta = new - original;
                    app.XPointsROI{ind}.Position(3) = app.XPointsROI{ind}.Position(3) + delta;
                    
                    centerTime = app.XPointsROI{ind}.Position(1) + app.XPointsROI{ind}.Position(3)/2;
                    
                    app.TimeFields(ind) = centerTime;
                    app.TimeWidths(ind) = app.XPointsROI{ind}.Position(3);
                    else
                    delta = original - new;
                    app.XPointsROI{ind}.Position(3) = app.XPointsROI{ind}.Position(3) - delta;
                    
                    centerTime = app.XPointsROI{ind}.Position(1) + app.XPointsROI{ind}.Position(3)/2;
                    
                    app.TimeFields(ind) = centerTime;
                    app.TimeWidths(ind) = app.XPointsROI{ind}.Position(3);
                    end
                    
                    
                    %WIDTH STAYS STATIC AND LOOKS AS IF IT GETS BIGGER OR
                    %SMALLER BECAUSE OF SCALE. NEED TO UPDATE AS IT MOVES
                    %BUT KEEP CONDITION EQUAL
    
                    %update rectangle positions with event source position
                    %at the very end but can use each separately up until
                    %then
                end
                
                Tester(app);
                RGPlot(app);
            end
        end


        function plotLogData(app)
            % Populate variables with loaded properties
            app.KinDataPlot = app.KinData(:,app.KinInd);
            app.Traces = size(app.Table, 2) - 1;

            % LogPlot isn't a property so it must be checked for existence
            if exist('LogPlot', 'var') == 1
                delete(LogPlot); %#ok<NODEF>
            end

            % Plot loaded log data
            LogPlot = plot(app.KinPlot, table2array(app.timeRG), table2array(app.KinDataPlot), 'b'); %#ok<NASGU>

            % Check if Y-axis is to be kept constant
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) min(table2array(app.KinDataPlot)) max(table2array(app.KinDataPlot))]);
            else
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) app.FixedYRange]);
            end

            % Keep X scale Logarithmic
            app.KinPlot.XScale = 'log';

            % Check if lin or log scale
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
                KinRef = refline(app.KinPlot, 0, 0);
                KinRef.Color = [0 0 0];
            end

            % Display proper wavelength on app
            if app.TamOn == 0
                app.KineticLevelDisplay.Text = "Displaying kinetic at " + app.Plot_Wavelengths(app.KinInd) + " nm";
            else
                app.KineticLevelDisplay.Text = "Disp: " + app.positions(app.KinInd);
            end
        end

        function clearProperties(app)
            % Set all properties back to default values
            app.TamOn = 0;
            app.waves = {};
            app.positions = {};
            app.BackgroundLevel = {};
            app.LinDataSort = {};
            app.LogDataSort = {};
            app.dataRG = {};
            app.timeRG = {};
            app.IsLogDataOnly = 0;
            app.changeAxes = 0;
            app.FixedYRange = [];
            app.SpecYRange = [];
            app.KinDataPlot = [];
            app.SpecDataPlot = [];
            delete(app.DataPlot);
            app.ShadeArea = [];
            app.Traces = [];
            app.Table = {};
            app.XPoints = [];
            app.XPointsROI = {};
            app.KinShade = [];
            app.Path = "";
            app.LinearData = {};
            app.LogarithmData = {};
            app.time = [];
            app.LogTimeArr = [];
            app.KinInd = 1;
            app.Plot_Wavelengths = {};

            app.LinearYscaleButton.Value = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.KeepSpecPlotYaxisfixedCheckBox.Value = 0;
            app.TimeFields = [1E-6; 1E-5; 1E-4; 1E-3; 1E-2; 1E-1];
            app.TimeWidths = [0.1; 0.1; 0.1; 0.1; 0.1; 0.1];

            cla(app.KinPlot, "reset")
            cla(app.SpecPlot, "reset")
        end

        function plotKinData(app)
            % Plot the data on axes figure
            delete(app.DataPlot)
            hold(app.KinPlot, 'on');
            app.DataPlot = plot(app.KinPlot, table2array(app.timeRG), table2array(app.KinDataPlot), 'b');
            hold(app.KinPlot, 'off');
        end

        function Tester(app,~)
            %% Calculate new x positions and num of spec data
            TimeRGSpec = app.LinDataSort(:,1);
            for i = 1:1:size(app.TimeFields,1)
                CenterTime = app.TimeFields(i);
                
                StartTime = CenterTime *(1 - (app.TimeWidths(i)/CenterTime)/2);
                EndTime = CenterTime * (1 + (app.TimeWidths(i)/CenterTime)/2);

                StartInd = find(StartTime > table2array(TimeRGSpec),1,'last') + 1;
                EndInd = find(EndTime < table2array(TimeRGSpec),1,'first') - 1;

                app.SpecDataPlot(:,i) = mean(table2array(app.LinDataSort(StartInd:EndInd,2:end)),1);
                if nargin == 1
                    app.ShadeArea(i,1:2) = [StartTime EndTime];
                end
            end
        end

        function deleteRow(app, indices)
            ind = indices(1);
            app.UITable.Data(ind,:) = [];
            
            app.TimeFields(ind) = [];
            app.TimeWidths(ind) = [];
            
            app.XPointsROI{ind,1} = [];
            app.XPointsROI{ind,2} = [];
            app.XPoints(ind,:) = [];
            
            app.KinShade{ind} = [];
            app.KinShade = app.KinShade(~cellfun('isempty',app.KinShade));
            
            app.SpecDataPlot(:,ind) = [];
            app.ShadeArea(ind,:) = [];
            
            Tester(app);
            cla(app.KinPlot,"reset");
            updateData(app);
            plotKinData(app);
            YCheck(app);
            drawAreas(app);
            RGPlot(app);
        end
        
        function addRow(app)
            % Determine end magnitude
            last = (app.UITable.Data(end, 1));
            spt = split (last,'e');
            num2 = spt(2);
            mag0 = str2double(num2);
            mag = mag0 + 1;
            mag = 10^mag;
            
            % Add new field values, then clear and update app
            app.TimeFields(end + 1) = 1.00*mag;
            app.TimeWidths(end + 1) = 0.1*app.TimeFields(end);
            
            Tester(app);
            cla(app.KinPlot,"reset");
            updateData(app);
            plotKinData(app);
            YCheck(app);
            drawAreas(app);
            RGPlot(app);
        end

        function drawAreas(app)
            app.XPointsROI = {};
            RGcolor = hsv(8);
            RGcolor(:,3) = 0.5;
            RGcolor(4, 3) = 1;
            
            for i = 1:1:size(app.TimeFields)
                    height = app.FixedYRange(2)-app.FixedYRange(1)+0.058;
                    % ADD CONTEXT MENU OPTION TO GET RID OF VERTICES FOR IMG
                    area = drawrectangle(app.KinPlot, "Position", [app.ShadeArea(i,1) app.FixedYRange(1)-0.005 app.TimeWidths(i) height], 'Color', RGcolor(i,:), 'DrawingArea',[app.KinPlot.XLim(1) app.FixedYRange(1)-0.005 app.KinPlot.XLim(2)-app.KinPlot.XLim(1) height] ,'Tag', num2str(i), 'LineWidth',1);

                    %% Add callbacks for when point if moved
                    addlistener(area, "MovingROI", @(src,event)RGBuildSpecData(app,src,event));

                    %% Store points in vector
                    app.XPointsROI{i} = area;
                    app.XPoints(i,:) = [app.XPointsROI{i}.Position(1) app.XPointsROI{i}.Position(1)+app.XPointsROI{i}.Position(3)];

            end
        end
        
        function YCheck(app)
             % Check if Y-axis is to be kept constant
            if app.KeepKinPlotaxisfixedCheckBox.Value == 0
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) min(table2array(app.KinDataPlot)) max(table2array(app.KinDataPlot))]);
                app.FixedYRange = [min(table2array(app.KinDataPlot)) max(table2array(app.KinDataPlot))];
            else
                axis(app.KinPlot,[1E-7 max(table2array(app.timeRG)) app.FixedYRange]);
            end
        end
        
        function updateData(app)
            % Pull most up to date data and populate prop.
            Kindata = app.dataRG(:,2:end);
            app.KinDataPlot = Kindata(:,app.KinInd);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Determine where your m-file's folder is.
            folder = fileparts(which(mfilename));

            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));

            % Keep most buttons invisble for cleaner startup look
            app.GridLayout3.Visible = false;
            app.GridLayout9.Visible = false;
            app.CenterPanel.Visible = false;
            app.RightPanel.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.LogLogFigureButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
            app.rst = 0;
            app.iter = 0;
            app.TimeFields = [1E-6; 1E-5; 1E-4; 1E-3; 1E-2; 1E-1];
        end

        % Button pushed function: LoadTDMSdataButton
        function loadDataButton(app, event)
            app.LoadTDMSdataButton.Enable = 'off';
            app.iter = app.iter + 1;
            % Check if we start from startup fcn or reset fcn
            % Set path accordingly
            if app.rst == 0
                try
                    app.LoadCreateError.Text = "Loading data...";
                    FolderPath = uigetdir;
                    FolderContent = dir(FolderPath); %#ok<NASGU>
                    app.LoadTDMSdataButton.Enable = 'on';
                catch
                    app.LoadCreateError.Text = 'Invalid directory, try again';
                    app.LoadTDMSdataButton.Enable = 'on';
                end
            else
                folder = fileparts(which(mfilename));
                addpath(genpath(folder));
                FolderPath = app.Path;
                FolderContent = dir(FolderPath); %#ok<NASGU>
                app.LoadTDMSdataButton.Enable = 'on';
            end

            % Load the appropriate script file to handle loaded TDMS data
            try
                SpectraBuilderloadTDMSfolder;
                %% Check if matlab is installed
                matlab.addons.isAddonEnabled('Image Processing Toolbox')
            catch ERROR
                switch ERROR.identifier
                    case 'MATLAB:UndefinedFunction'
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        return;
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install 'Image Processing Toolbox' to continue";
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Unknown error';
                        return;
                end
            end

            % Separate property to distinguish proper path
            app.Path = FolderPath;

            % Set all the variables from the loadTDMSfolder to public and
            % private properties for both applications to call on
            app.LinearData = LinData;
            app.LogarithmData = LogData;
            app.TamOn = tamOn;
            app.time = Time;
            app.LogTimeArr = LogTimeArray;
            app.BackgroundLevel = BackgroundLvl;
            app.KinInd = 1;
            app.IsLogDataOnly=false;
            app.changeAxes = true;

            % Reveal all the hidden objects
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.SpecPlot.Visible = true; %RG
            app.PlotDetailsLabel.Visible = true;
            app.LinearDataButton.Visible = true;
            app.LogDataButton.Visible=true;
            app.ExportLogDataCSVButton.Visible = true;
            app.ExportSpectraDataButton.Visible = true;
            app.CentertimesforspectraalongwiththeirwidthsLabel.Visible = true;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = true;
            app.BackgroundLvlDisplay.Visible = true;
            app.SaveVarstoWorkspaceButton.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.ExportFiguresButton.Visible = true;
            app.ResetButton.Visible = true;
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.ButtonGroup.SelectedObject = app.LinearYscaleButton;
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = false;
            app.rst = 0;

            % Sort the table columns by wavelength & check if TAS or TAM
            if app.TamOn == 0
                %% Look at the data tables and sort by wavelengths
                app.waves = app.LinearData.Properties.VariableNames(2:end);
                wavelengths_order = cellfun(@(x)sscanf(x, 'x%d'), app.waves);
                [~, sortedWavelengthsOrder] = sort(wavelengths_order);
                app.BackgroundLevel = app.BackgroundLevel(:,sortedWavelengthsOrder);

                %Change sorting array to fit Time in first index
                sortedWavelengthsOrder = sortedWavelengthsOrder + 1;
                sortedWavelengthsOrder = [1 sortedWavelengthsOrder];
                app.LogDataSort = app.LogarithmData(:, sortedWavelengthsOrder);
                app.LinDataSort = app.LinearData(:, sortedWavelengthsOrder);

                % Populate property to use throughout fcns
                % And erase the useless 'x' char in wavelength num
                app.dataRG = app.LogDataSort;
                for i = 1:1:length(app.waves)
                    app.waves(i) = erase(app.waves(i), 'x');
                end

                % Output folder path in text box and continue
                [~, name] = fileparts(app.Path);
                app.LoadCreateError.Text = "Opened: " + name;

                RGBuildSpecData(app);
            else
                %% Look at the data tables and sort by position
                app.waves = {Wavelength};

                app.positions = app.LinearData.Properties.VariableNames(2:end);
                positions_order = cellfun(@(x)sscanf(x, 'x%d'), app.positions);
                [~, sortedPositionsOrder] = sort(positions_order);
                app.BackgroundLevel = app.BackgroundLevel(:,sortedPositionsOrder);

                %Change sorting array to fit Time in first index
                sortedPositionsOrder = sortedPositionsOrder + 1;
                sortedPositionsOrder = [1 sortedPositionsOrder];
                app.LogDataSort = app.LogarithmData(:, sortedPositionsOrder);
                app.LinDataSort = app.LinearData(:, sortedPositionsOrder);

                % Populate property to use throughout fcns
                % And erase the useless 'x' char in wavelength num
                app.dataRG = app.LogDataSort;
                for i = 1:1:length(app.positions)
                    app.positions(i) = erase(app.positions(i), 'x');
                end

                % Output folder path in text box and continue
                [~, name] = fileparts(app.Path);
                app.LoadCreateError.Text = "Opened folder: " + name;

                RGBuildSpecData(app);
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPressed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd - 1;
            if app.KinInd < 1
                app.KinInd = 1;
            end

            % Check if need to plot Log data or Lin data
            if ~app.IsLogDataOnly
                updateData(app);
                plotKinData(app);
                YCheck(app);
                RGPlot(app);
            else
                plotLogData(app);
            end
        end

        % Button pushed function: NextButton
        function NextButtonPressed(app, event)
            % Alter index accordingly and replot data
            app.KinInd = app.KinInd + 1;
            if app.KinInd > app.Traces
                app.KinInd = app.Traces;
            end

            % Check if need to plot Log data or Lin data
            if ~app.IsLogDataOnly
                updateData(app);
                plotKinData(app);
                YCheck(app);
                RGPlot(app);
            else
                plotLogData(app);
            end
        end

        % Value changed function: KeepSpecPlotYaxisfixedCheckBox
        function KeepSpecPlotYaxisfixed(app, event)
            % Keep y-axis fixed for the spec plot
            app.changeAxes = ~ app.changeAxes;
            RGPlot(app)
        end

        % Value changed function: KeepKinPlotaxisfixedCheckBox
        function KeepKinPlotaxisfixed(app, event)
            % Keep y-axis fixed for the kin plot based on log or lin
            if app.KeepKinPlotaxisfixedCheckBox.Value==1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataArray = table2array(app.KinDataPlot);
                        TimeArray = table2array(app.timeRG);
                        KinDataPlotPos = (KinDataArray(TimeArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        app.FixedYRange = [min(KinDataPlotPos) max(KinDataArray)];
                    case 'linear'
                        app.FixedYRange = app.KinPlot.YLim;
                end
            end

            % Check if need to re-plot Log data or Lin data
            if ~app.IsLogDataOnly
                RGPlot(app)
            else
                plotLogData(app)
            end
        end

        % Selection changed function: ButtonGroup
        function selectLogOrLin(app, event)
            % Create var for selected scale and change accordingly
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.LogYScaleButton
                app.KinPlot.YScale = 'log';
            else
                app.KinPlot.YScale = 'linear';
                refline(app.KinPlot, 0, 0);
            end

            % Check if the y-axis is fixed and update fixed y range
            if app.KeepKinPlotaxisfixedCheckBox.Value == 1
                switch app.KinPlot.YScale
                    case 'log'
                        KinDataArray = table2array(app.KinDataPlot);
                        TimeArray = table2array(app.timeRG);
                        KinDataPlotPos = (KinDataArray(TimeArray>0));
                        KinDataPlotPos = (KinDataPlotPos(KinDataPlotPos>0));
                        app.FixedYRange = [min(KinDataPlotPos) max(KinDataArray)];
                    case 'linear'
                        app.FixedYRange = app.KinPlot.YLim;
                end
            end

            % Only call on building spectra if using lin data (nargin=3)
            if ~app.IsLogDataOnly
                %%%%%%%%%%%%%%%%%FIX THIS. SHADED AREA IS SHOWING UP NOW%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %% JUST TURN OFF VISIBILITY FOR ALL OBJECTS IF ALL STORED?
                %% CHECKOUT SUPPRESSED MESSAGES THROUGHOUT APP
                RGBuildSpecData(app,1,1);
            end
        end

        % Button pushed function: LinearDataButton
        function setLinData(app, event)
            % Populate the correct property and build specdata again
            app.dataRG = app.LinDataSort;
            RGBuildSpecData(app);
        end

        % Button pushed function: LogDataButton
        function setLogData(app, event)
            % Populate the correct property and build specdata again
            app.dataRG = app.LogDataSort;
            RGBuildSpecData(app);
        end

        % Button pushed function: ExportLogDataCSVButton
        function ExportLogDataCSVButtonPushed(app, event)
            % Reveal export message
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = true;

            % Create file name/path to export to
            [~, name] = fileparts(app.Path);
            if app.TamOn == 0
                name = app.Path + "\" + name + "_KIN_TAS.csv";
            else
                name = app.Path + "\" + name + "_KIN_TAM.csv";
            end

            % Write CSV to table and return a message notifying as such
            writetable(app.LogDataSort, name, "Delimiter", ',', "QuoteStrings",true);
            app.LogarithmicdatawillbeexportedtoCSVLabel.Text = 'Data has been exported';
        end

        % Button pushed function: ExportSpectraDataButton
        function ExportSpectraDataButtonPushed(app, event)
            % Use path from data folder to export spectra data
            [~, name] = fileparts(app.Path);
            OutputName = app.Path + "\" + name + "_SPECTRADATA.txt";
            sorted_wavelengths = sort(str2double(app.waves));
            DataToWrite = [sorted_wavelengths' app.SpecDataPlot];

            % Create vars to export data to txt
            Headers = [];
            for i =1:1:size(app.SpecDataPlot,2)
                Headers(1,i+1) = mean(app.ShadeArea(i,:)); %#ok<AGROW>
            end
            OutputData = [Headers; DataToWrite]';
            format_str = ['%.1f' repmat(' %.7f',1,size(app.SpecDataPlot,2)) '\r\n'];
            RGID = fopen(OutputName,'w');
            fprintf(RGID,format_str,OutputData);
            fclose(RGID);
        end

        % Button pushed function: LoadLogData
        function loadLogDataButtonPushed(app, event)
            app.LoadLogData.Enable = 'off';
            % Find the csv file and record path and clear plots
            app.LoadCreateError.Text = "Loading data";
            [FileName, FilePath] = uigetfile('*.csv');
            app.LoadCreateError.Text = "log data: " + FileName;

            try
                % Read the data and copy to public property to use
                temp = readtable(fullfile(FilePath, FileName));
            catch ERROR
                switch ERROR.identifier
                    case 'matlab_addons:enableDisableManagement:invalidIdentifier'
                        app.LoadCreateError.Text = "Please install 'Image Processing Toolbox' to continue";
                        app.loadLogDataButton.Enable = 'on';
                        return;
                    otherwise
                        app.LoadCreateError.Text = 'Function is not on selected path';
                        app.loadLogDataButton.Enable = 'on';
                        return;
                end
            end
            app.loadLogDataButton.Enable = 'on';

            % Clear all previous properties that were populated and set
            % index to default value
            clearProperties(app);
            app.KinInd = 1;

            cla(app.KinPlot, "reset");
            cla(app.SpecPlot,"reset");
            app.KeepKinPlotaxisfixedCheckBox.Value = 0;
            app.ButtonGroup.SelectedObject = app.LinearYscaleButton;

            % Check if it is TAS or TAM data and edit names for global use
            if endsWith(FileName, '.csv') == 1
                if endsWith(FileName, 'TAS.csv') == 1
                    app.TamOn = 0;
                elseif endsWith(FileName, 'TAM.csv') == 1
                    app.TamOn = 1;
                else
                    msg = "Wrong input file. Need TAS or TAM data";
                    app.LoadCreateError.Text = msg;
                end
            end

            % Re-apply temp variables to properties to keep useful data
            app.Table = temp;

            if app.TamOn == 0
                % Extract csv header names
                app.waves = app.Table.Properties.VariableNames(2:end);

                % Erase useless 'x' char in wavelength num
                for i = 1:1:length(app.waves)
                    app.waves(i) = erase(app.waves(i), 'x');
                end
            else
                % Extract csv header names
                app.positions = app.Table.Properties.VariableNames(2:end);
                app.waves = extractBefore(app.positions,'nm');

                % Erase useless 'x' char in position num
                for i = 1:1:length(app.positions)
                    app.positions(i) = erase(app.positions(i), 'x');
                end
            end

            % Change look of application to get rid of options that are not
            % available to loaded log data
            app.IsLogDataOnly = true;
            app.Plot_Wavelengths = sort(str2double(app.waves));
            app.GridLayout3.Visible = true;
            app.GridLayout9.Visible = true;
            app.CenterPanel.Visible = true;
            app.RightPanel.Visible = true;
            app.LogLogFigureButton.Visible = true;
            app.SpecPlot.Visible = false;
            app.PlotDetailsLabel.Visible = false;
            app.LinearDataButton.Visible = false;
            app.LogDataButton.Visible=false;
            app.SpectraTimeWidthSlider.Visible = false;
            app.ExportLogDataCSVButton.Visible = false;
            app.ExportSpectraDataButton.Visible = false;
            app.CentertimesforspectraalongwiththeirwidthsLabel.Visible = false;
            app.KeepSpecPlotYaxisfixedCheckBox.Visible = false;
            app.BackgroundLvlDisplay.Visible = false;
            app.SaveVarstoWorkspaceButton.Visible = false;
            app.ExportFiguresButton.Visible = false;
            app.ResetButton.Visible = false;
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = false;

            % After everything is loaded, plot data accordingly
            app.timeRG = app.Table(:,1);
            app.KinData = app.Table(:,2:end);
            plotLogData(app);
        end

        % Button pushed function: SaveVarstoWorkspaceButton
        function SaveVarstoWorkspaceButtonPushed(app, event)
            % Call on these properties in order to save to workspace
            app.SpecDataPlot;
            app.dataRG;
            app.BackgroundLevel;

            % Save vars of this function to base workspace
            save_to_base(1);
        end

        % Button pushed function: LogLogFigureButton
        function LogLogFigureButtonPushed(app, event)
            % Disable button while second app is open
            app.LogLogFigureButton.Enable = 'off';

            % Open LogLog App with required arguments
            app.LogApp = LogLogApp(app);
        end

        % Close request function: SpectrabuilderUIFigure
        function SpectrabuilderUIFigureCloseRequest(app, event)
            % Delete both apps when closing the figures
            delete(app.LogApp)
            delete(app)
        end

        % Button pushed function: ExportFiguresButton
        function ExportFiguresButtonPushed(app, event)
            % Create string for figure titles
            string = "@" + app.Plot_Wavelengths(app.KinInd);
            name1 = sprintf('KinFigure%s.pdf',string);
            name2 = sprintf('SpecFigure%s.png',string);

            % Create full path for each name
            place1 = fullfile(app.Path,name1);
            place2 = fullfile(app.Path,name2);

            % Export Vector Image for KinPlot and PNG for SpecPlot
            exportgraphics(app.KinPlot, place1, 'ContentType','vector');
            exportgraphics(app.SpecPlot, place2, 'Resolution', 300);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Create property to know when reset button is pushed
            app.rst = 1;

            % Save path of loaded data so you don't have to call data again
            % Then clear properties and reset to default
            tempPath = app.Path;
            clearProperties(app);
            app.Path = tempPath;

            loadDataButton(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Store important properties about the change like indices
            newData = event.NewData;
            indices = event.Indices;
            i = indices(1);
            
            if indices(2) == 1 
                % Store old and new times field values
                previousTime = app.TimeFields(i);
                newTime = str2double(newData);
    
                % If the number doesn't change, do nothing
                if previousTime == str2double(newData)
                    return
                elseif newData == ""
                    deleteRow(app, indices);
                    return
                elseif isnan(newTime)
                    app.UITable.Data(indices) = {sprintf('%.2e\n',previousTime)};
                    return
                end
    
                % Update the array spot with new data in double form
                app.TimeFields(i) = newTime;
                StartTime = newTime*(1 - (app.UITable.Data{i,2})/200);
                EndTime = newTime*(1 + (app.UITable.Data{i,2})/200);

                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];

                % Make sure the newly added/edited timeFields is sorted
                [app.TimeFields,I] = sort(app.TimeFields);
                app.TimeWidths = app.TimeWidths(I);
                
                Tester(app,1);
                cla(app.KinPlot,"reset");

                drawAreas(app);
                updateData(app);
                plotKinData(app);
                YCheck(app);
                RGPlot(app);
            else
                % Store old width and new width in vars
                previousWidth = app.TimeWidths(i);
                newWidth = newData;
                
                % If the number doesn't change, do nothing
                if previousWidth == str2double(newData)
                    return
                elseif isnan(newData)
                    deleteRow(app, indices);
                    return
                end
                
                % Calculate the positions of the new width from the same centre
                newWidth = newWidth/100;
                StartTime = app.TimeFields(i)*(1 - newWidth/2);
                EndTime = app.TimeFields(i) * (1 + newWidth/2);
                
                % Manually update each property with these new positions
                app.TimeWidths(i) = EndTime-StartTime;
                app.ShadeArea(i,:) = [StartTime EndTime];
                app.XPointsROI{i}.Position(1) = StartTime;
                app.XPointsROI{i}.Position(3) = app.TimeWidths(i);
                app.XPoints(i,:) = [StartTime EndTime];
                
                Tester(app);
                updateData(app);
                plotKinData(app);
                YCheck(app);
                RGPlot(app);
            end

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            addRow(app);
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
            YCheck(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrabuilderUIFigure = uifigure('Visible', 'off');
            app.SpectrabuilderUIFigure.Name = 'Spectrabuilder';
            app.SpectrabuilderUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpectrabuilderUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SpectrabuilderUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadTDMSdataButton = uibutton(app.GridLayout2, 'push');
            app.LoadTDMSdataButton.ButtonPushedFcn = createCallbackFcn(app, @loadDataButton, true);
            app.LoadTDMSdataButton.Text = 'Load TDMS data';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.LogDataButton = uibutton(app.GridLayout9, 'push');
            app.LogDataButton.ButtonPushedFcn = createCallbackFcn(app, @setLogData, true);
            app.LogDataButton.Text = 'Log Data';
            app.LinearDataButton = uibutton(app.GridLayout9, 'push');
            app.LinearDataButton.ButtonPushedFcn = createCallbackFcn(app, @setLinData, true);
            app.LinearDataButton.Text = 'Linear Data';
            app.LoadCreateError = uilabel(app.GridLayout2);
            app.LoadCreateError.Text = '';
            app.LoadLogData = uibutton(app.GridLayout2, 'push');
            app.LoadLogData.ButtonPushedFcn = createCallbackFcn(app, @loadLogDataButtonPushed, true);
            app.LoadLogData.Text = 'Load Log Data';
            app.SaveVarstoWorkspaceButton = uibutton(app.GridLayout2, 'push');
            app.SaveVarstoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVarstoWorkspaceButtonPushed, true);
            app.SaveVarstoWorkspaceButton.Text = 'Save Vars to Workspace';
            app.ExportFiguresButton = uibutton(app.GridLayout2, 'push');
            app.ExportFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFiguresButtonPushed, true);
            app.ExportFiguresButton.Text = 'Export Figures';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LogLogFigureButton = uibutton(app.GridLayout2, 'push');
            app.LogLogFigureButton.ButtonPushedFcn = createCallbackFcn(app, @LogLogFigureButtonPushed, true);
            app.LogLogFigureButton.Text = 'LogLog Figure';
            app.LogarithmicdatawillbeexportedtoCSVLabel = uilabel(app.GridLayout2);
            app.LogarithmicdatawillbeexportedtoCSVLabel.Visible = 'off';
            app.LogarithmicdatawillbeexportedtoCSVLabel.Text = 'Logarithmic data will be exported to CSV';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.SpecPlot = uiaxes(app.GridLayout3);
            title(app.SpecPlot, 'Spectra')
            xlabel(app.SpecPlot, 'Wavelength (nm)')
            ylabel(app.SpecPlot, 'ΔOD or %Abs')
            app.KinPlot = uiaxes(app.GridLayout3);
            title(app.KinPlot, 'Kinetics')
            xlabel(app.KinPlot, 'Time (s)')
            ylabel(app.KinPlot, 'ΔOD or %Abs')
            app.KeepSpecPlotYaxisfixedCheckBox = uicheckbox(app.GridLayout3);
            app.KeepSpecPlotYaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepSpecPlotYaxisfixed, true);
            app.KeepSpecPlotYaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.PrevButton = uibutton(app.GridLayout6, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPressed, true);
            app.PrevButton.Text = 'Prev';
            app.NextButton = uibutton(app.GridLayout6, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPressed, true);
            app.NextButton.Text = 'Next';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.ButtonGroup = uibuttongroup(app.GridLayout5);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectLogOrLin, true);
            app.LogYScaleButton = uiradiobutton(app.ButtonGroup);
            app.LogYScaleButton.Text = 'Log Y Scale';
            app.LinearYscaleButton = uiradiobutton(app.ButtonGroup);
            app.LinearYscaleButton.Text = 'Linear Y scale';
            app.LinearYscaleButton.Value = true;
            app.CenterButton = uibutton(app.GridLayout5, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.KeepKinPlotaxisfixedCheckBox = uicheckbox(app.GridLayout5);
            app.KeepKinPlotaxisfixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepKinPlotaxisfixed, true);
            app.KeepKinPlotaxisfixedCheckBox.Text = 'Keep Y axis fixed';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.PlotDetailsLabel = uilabel(app.GridLayout4);
            app.PlotDetailsLabel.Text = 'Plot Details';
            app.ExportSpectraDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportSpectraDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpectraDataButtonPushed, true);
            app.ExportSpectraDataButton.Text = 'Export SpectraData';
            app.BackgroundLvlDisplay = uilabel(app.GridLayout4);
            app.BackgroundLvlDisplay.Text = 'Background level was _ V';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'Centre Time'; 'Time Width (%)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.KineticLevelDisplay = uilabel(app.GridLayout4);
            app.KineticLevelDisplay.Text = 'Displaying kinetic at _ nm';
            app.ExportLogDataCSVButton = uibutton(app.GridLayout4, 'push');
            app.ExportLogDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportLogDataCSVButtonPushed, true);
            app.ExportLogDataCSVButton.Text = 'Export Log Data CSV';
            app.CentertimesforspectraalongwiththeirwidthsLabel = uilabel(app.GridLayout4);
            app.CentertimesforspectraalongwiththeirwidthsLabel.Text = {'Center times for spectra'; 'along with their widths'};
            app.AddButton = uibutton(app.GridLayout4, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            % Show the figure after all components are created
            app.SpectrabuilderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SolarSpecGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrabuilderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrabuilderUIFigure)
        end
    end
end